classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RefreshpointsButton          matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        Spinner                      matlab.ui.control.Spinner
        SpinnerLabel                 matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ShowaprocimationcurveSwitch  matlab.ui.control.Switch
        ShowaprocimationcurveSwitchLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            point_amount = app.Spinner.Value;           % Current spinner value
            previous_point_amount = event.PreviousValue;% Spinner value before change
            set(app.UITable, 'RowName', 'numbered');    
            if (point_amount > previous_point_amount)   % Increment
                default_values = {0, 0};
                old_data = get(app.UITable, 'data');    % Save table
                new_data = [old_data;default_values];   % New coordinates = previous coordinates & default coordinates
                set(app.UITable, 'data', new_data);     % Add new coordinates to table
            else                                        % Decrement -> delete last row
                app.UITable.Data((previous_point_amount), :) = [];
            end
        end

        % Button pushed function: RefreshpointsButton
        function RefreshpointsButtonPushed(app, event)
            t = cell2mat(app.UITable.Data);
%             polynomial_aproximation_value = get(app.Spinner, 'Value') - 1;
            polynomial_aproximation_coefficients = polyfit(t(:,1), t(:,2), 2);
            
            x = t(:,1);
            y = t(:,2);
            x1 = min(t(:,1)) - 1 : 0.1 : max(t(:,1)) + 1;
            y1 = polyval(polynomial_aproximation_coefficients, x1);
            
            show_aproximation_curve = get(app.ShowaprocimationcurveSwitch, 'Value');
            
            switch show_aproximation_curve
                case 'On'
                    plot(app.UIAxes, x1, y1, x, y, 'o');
                case 'Off'
                    plot(app.UIAxes, x, y, 'O');
            end
            
        end

        % Value changed function: ShowaprocimationcurveSwitch
        function ShowaprocimationcurveSwitchValueChanged(app, event)
            RefreshpointsButtonPushed(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = 'Počet bodů';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Editable = 'off';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RefreshpointsButton = uibutton(app.LeftPanel, 'push');
            app.RefreshpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpointsButtonPushed, true);
            app.RefreshpointsButton.Text = 'Refresh points';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ShowaprocimationcurveSwitchLabel = uilabel(app.RightPanel);
            app.ShowaprocimationcurveSwitchLabel.Text = 'Show aprocimation curve';
            app.ShowaprocimationcurveSwitch = uiswitch(app.RightPanel, 'slider');
            app.ShowaprocimationcurveSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowaprocimationcurveSwitchValueChanged, true);
            app.ShowaprocimationcurveSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end